classdef BeakLens < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        Image         matlab.ui.control.Image
        EditField     matlab.ui.control.EditField
        DetectButton  matlab.ui.control.Button
        UploadButton  matlab.ui.control.Button
        UIAxes2       matlab.ui.control.UIAxes
        UIAxes        matlab.ui.control.UIAxes
    end

   properties (Access=private)
       TNet = load("trainedNetwork_2.mat");
       adata = double.empty;
   end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: UploadButton
        function UploadButtonPushed(app, event)
             % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));

            % Allow the user to choose an image file
            [file, path] = uigetfile({'*.jpg;*.jpeg;*.png;*.bmp', 'Image Files (*.jpg, *.jpeg, *.png, *.bmp)'}, 'Select an Image File');

            % Check if the user selected a file
            if isequal(file, 0)
                disp('User canceled the operation');
            else
                % Construct the full path to the selected image
                imagePath = fullfile(path, file);

                % Read and resize the selected image
                img = imread(imagePath);
                img = imresize(img, [227 227]);

                % Display the selected image in UIAxes
                imshow(img, 'Parent', app.UIAxes);
            end 
        
        end

        % Button pushed function: DetectButton
        function DetectButtonPushed(app, event)
            global YPred;

% Check if an image is loaded in UIAxes
            if isempty(app.UIAxes.Children)
                msgbox('Please upload an image first.', 'No Image', 'warn');
                return;
            end

            % Get the image data from UIAxes
            img = app.UIAxes.Children.CData;

            % Resize the image
            img = imresize(img, [227 227]);

            % Perform image classification
            [YPred, probs] = classify(app.TNet, img);

            % Display results in UIAxes2
            imshow(img, 'Parent', app.UIAxes2);
            app.EditField.Value=string(YPred);

            % Draw a red box on the original image
            x = 50;      % X-coordinate of the top-left corner
            y = 50;      % Y-coordinate of the top-left corner
            width = 127; % Width of the box
            height = 127; % Height of the box
        
            hold(app.UIAxes2, 'on');  % Ensure that the box is drawn on top of the image
            rectangle(app.UIAxes2, 'Position', [x, y, width, height], 'EdgeColor', 'r', 'LineWidth', 2);
            hold(app.UIAxes2, 'off');  % Release the hold on the axis

        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Button down function: UIAxes2
        function UIAxes2ButtonDown(app, event)
            
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            
        end

        % Button pushed function: DetectButton
        function DetectButtonPushed2(app, event)
        global YPred;

% Check if an image is loaded in UIAxes
            if isempty(app.UIAxes.Children)
                msgbox('Please upload an image first.', 'No Image', 'warn');
                return;
            end

            % Get the image data from UIAxes
            img = app.UIAxes.Children.CData;

            % Resize the image
            img = imresize(img, [224 224]);

            % Perform image classification
            [YPred, probs] = classify(app.TNet.trainedNetwork_1,img);

            % Display results in UIAxes2
            imshow(img, 'Parent', app.UIAxes2);
            app.EditField.Value=string(YPred);

            % Draw a red box on the original image
          %  x = 50;      % X-coordinate of the top-left corner
          %  y = 50;      % Y-coordinate of the top-left corner
          %  width = 127; % Width of the box
          %  height = 127; % Height of the box
      
          %    hold(app.UIAxes2, 'on');  % Ensure that the box is drawn on top of the image
          %    rectangle(app.UIAxes2, 'Position', [x, y, width, height], 'EdgeColor', 'r', 'LineWidth', 2);
          %    hold(app.UIAxes2, 'off');  % Release the hold on the axis


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Image')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Identified Image ')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes2.ButtonDownFcn = createCallbackFcn(app, @UIAxes2ButtonDown, true);
            app.UploadButton = uibutton(app.UIFigure, 'push');
            app.UploadButton.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonPushed, true);
            app.UploadButton.Text = 'Upload';
            app.DetectButton = uibutton(app.UIFigure, 'push');
            app.DetectButton.ButtonPushedFcn = createCallbackFcn(app, @DetectButtonPushed2, true);
            app.DetectButton.Text = 'Detect';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Value = 'Similiar Bird';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Bg img.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BeakLens

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end